classdef databasecreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GeneralPanel                  matlab.ui.container.Panel
        RobotNameEditField            matlab.ui.control.EditField
        RobotNameEditFieldLabel       matlab.ui.control.Label
        SavetoFileButton              matlab.ui.control.Button
        PhysicalCharacteristicsPanel  matlab.ui.container.Panel
        LegHeightEditField            matlab.ui.control.NumericEditField
        LegHeightEditFieldLabel       matlab.ui.control.Label
        LegWidthEditField             matlab.ui.control.NumericEditField
        LegWidthEditFieldLabel        matlab.ui.control.Label
        LegLengthEditField            matlab.ui.control.NumericEditField
        LegLengthEditFieldLabel       matlab.ui.control.Label
        BodyHeightEditField           matlab.ui.control.NumericEditField
        BodyHeightEditFieldLabel      matlab.ui.control.Label
        BodyWidthEditField            matlab.ui.control.NumericEditField
        BodyWidthEditFieldLabel       matlab.ui.control.Label
        BodyLengthEditField           matlab.ui.control.NumericEditField
        BodyLengthEditFieldLabel      matlab.ui.control.Label
        PleaseprovidetherelevantdataLabel  matlab.ui.control.Label
        NewRobotDatabaseLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        newrobot
    end
    
    properties (Access = private)
        mockuppart3 % Main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mockuppart3 = caller;
        end

        % Value changed function: BodyLengthEditField
        function BodyLengthEditFieldValueChanged(app, event)
            app.newrobot.bodylength = app.BodyLengthEditField.Value;
            
        end

        % Value changed function: LegHeightEditField
        function LegHeightEditFieldValueChanged(app, event)
            app.newrobot.legheight = app.LegHeightEditField.Value;
            
        end

        % Value changed function: LegWidthEditField
        function LegWidthEditFieldValueChanged(app, event)
            app.newrobot.legwidth = app.LegWidthEditField.Value;
            
        end

        % Value changed function: LegLengthEditField
        function LegLengthEditFieldValueChanged(app, event)
            app.newrobot.leglength = app.LegLengthEditField.Value;
            
        end

        % Value changed function: BodyHeightEditField
        function BodyHeightEditFieldValueChanged(app, event)
            app.newrobot.leglength = app.BodyHeightEditField.Value;
            
        end

        % Value changed function: BodyWidthEditField
        function BodyWidthEditFieldValueChanged(app, event)
            app.newrobot.bodywidth = app.BodyWidthEditField.Value;
            
        end

        % Value changed function: RobotNameEditField
        function RobotNameEditFieldValueChanged(app, event)
            app.newrobot.name = app.RobotNameEditField.Value;
            
        end

        % Button pushed function: SavetoFileButton
        function SavetoFileButtonPushed(app, event)
            % Bunch of stuff to save a file
            newrobotfilename = sprintf("%s.mat",app.newrobot.name);
            savedrobot = app.newrobot;
            [filename, filepath] = uiputfile({'*.mat', 'MAT-files (*.mat)'}, 'Save Robot', newrobotfilename);
            if ischar(filename)
                save(fullfile(filepath, filename), 'savedrobot');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the new database button again
            app.mockuppart3.NewDatabaseButton.Enable = "off";

            % Delete the new database builder
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NewRobotDatabaseLabel = uilabel(app.UIFigure);
            app.NewRobotDatabaseLabel.Text = 'New Robot Database';
            app.PleaseprovidetherelevantdataLabel = uilabel(app.UIFigure);
            app.PleaseprovidetherelevantdataLabel.Text = 'Please provide the relevant data.';
            app.PhysicalCharacteristicsPanel = uipanel(app.UIFigure);
            app.PhysicalCharacteristicsPanel.Title = 'Physical Characteristics';
            app.BodyLengthEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.BodyLengthEditFieldLabel.Text = 'Body Length';
            app.BodyLengthEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.BodyLengthEditField.ValueChangedFcn = createCallbackFcn(app, @BodyLengthEditFieldValueChanged, true);
            app.BodyLengthEditField.Value = 1;
            app.BodyWidthEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.BodyWidthEditFieldLabel.Text = 'Body Width';
            app.BodyWidthEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.BodyWidthEditField.ValueChangedFcn = createCallbackFcn(app, @BodyWidthEditFieldValueChanged, true);
            app.BodyWidthEditField.Value = 1;
            app.BodyHeightEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.BodyHeightEditFieldLabel.Text = 'Body Height';
            app.BodyHeightEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.BodyHeightEditField.ValueChangedFcn = createCallbackFcn(app, @BodyHeightEditFieldValueChanged, true);
            app.BodyHeightEditField.Value = 1;
            app.LegLengthEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.LegLengthEditFieldLabel.Text = 'Leg Length';
            app.LegLengthEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.LegLengthEditField.ValueChangedFcn = createCallbackFcn(app, @LegLengthEditFieldValueChanged, true);
            app.LegLengthEditField.Value = 1;
            app.LegWidthEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.LegWidthEditFieldLabel.Text = 'Leg Width';
            app.LegWidthEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.LegWidthEditField.ValueChangedFcn = createCallbackFcn(app, @LegWidthEditFieldValueChanged, true);
            app.LegWidthEditField.Value = 1;
            app.LegHeightEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.LegHeightEditFieldLabel.Text = 'Leg Height';
            app.LegHeightEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.LegHeightEditField.ValueChangedFcn = createCallbackFcn(app, @LegHeightEditFieldValueChanged, true);
            app.LegHeightEditField.Value = 1;
            app.SavetoFileButton = uibutton(app.UIFigure, 'push');
            app.SavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileButtonPushed, true);
            app.SavetoFileButton.Text = 'Save to File';
            app.GeneralPanel = uipanel(app.UIFigure);
            app.GeneralPanel.Title = 'General';
            app.RobotNameEditFieldLabel = uilabel(app.GeneralPanel);
            app.RobotNameEditFieldLabel.Text = 'Robot Name';
            app.RobotNameEditField = uieditfield(app.GeneralPanel, 'text');
            app.RobotNameEditField.ValueChangedFcn = createCallbackFcn(app, @RobotNameEditFieldValueChanged, true);
            app.RobotNameEditField.Value = 'newrobot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = databasecreator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end