classdef mockuppart3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenProjectMenu               matlab.ui.container.Menu
        RecentProjectsMenu            matlab.ui.container.Menu
        CISCORDUOMenu                 matlab.ui.container.Menu
        MEExcavatorMenu               matlab.ui.container.Menu
        SaveProjectMenu               matlab.ui.container.Menu
        NewDatabaseMenu               matlab.ui.container.Menu
        LoadDatabaseMenu              matlab.ui.container.Menu
        ExportDatabaseMenu            matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        OperationManualMenu           matlab.ui.container.Menu
        AboutThisToolMenu             matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        DatabaseBrowserTab            matlab.ui.container.Tab
        OverviewPanel                 matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        RestoreDefaultsButton         matlab.ui.control.Button
        DetailsPanel                  matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        NextButton                    matlab.ui.control.Button
        SaveSelectionButton           matlab.ui.control.Button
        AvailableDatabasesPanel       matlab.ui.container.Panel
        ExportDatabaseButton          matlab.ui.control.Button
        DeleteDatabaseButton          matlab.ui.control.Button
        NewDatabaseButton             matlab.ui.control.Button
        Tree                          matlab.ui.container.CheckBoxTree
        ETQuadNode                    matlab.ui.container.TreeNode
        MotorModelNode                matlab.ui.container.TreeNode
        BatteryNode                   matlab.ui.container.TreeNode
        LegCharacteristicsNode        matlab.ui.container.TreeNode
        MinitaurNode                  matlab.ui.container.TreeNode
        MotorModelNode_2              matlab.ui.container.TreeNode
        BatteryNode_2                 matlab.ui.container.TreeNode
        LegCharacteristicsNode_2      matlab.ui.container.TreeNode
        RHexNode                      matlab.ui.container.TreeNode
        MotorModelNode_3              matlab.ui.container.TreeNode
        BatteryNode_3                 matlab.ui.container.TreeNode
        LegCharacteristicsNode_3      matlab.ui.container.TreeNode
        RobotDesignTab                matlab.ui.container.Tab
        SubmitButton                  matlab.ui.control.Button
        ScalingRulesPanel             matlab.ui.container.Panel
        PreviousButton                matlab.ui.control.Button
        NextButton_2                  matlab.ui.control.Button
        SaveSelectionButton_2         matlab.ui.control.Button
        LogPanel                      matlab.ui.container.Panel
        ExportLogButton               matlab.ui.control.Button
        LoggingLevelDropDown          matlab.ui.control.DropDown
        LoggingLevelDropDownLabel     matlab.ui.control.Label
        TextArea_2                    matlab.ui.control.TextArea
        SketchViewPanel               matlab.ui.container.Panel
        NoloadSpeedRPMEditField       matlab.ui.control.NumericEditField
        NoloadSpeedRPMEditFieldLabel  matlab.ui.control.Label
        StallTorqueNmEditField        matlab.ui.control.NumericEditField
        StallTorqueNmEditFieldLabel   matlab.ui.control.Label
        BatteryPowerWEditField        matlab.ui.control.NumericEditField
        BatteryPowerWEditFieldLabel   matlab.ui.control.Label
        LegThicknesscmEditField       matlab.ui.control.NumericEditField
        LegThicknesscmEditFieldLabel  matlab.ui.control.Label
        LegWidthcmEditField           matlab.ui.control.NumericEditField
        LegWidthcmEditFieldLabel      matlab.ui.control.Label
        LegLengthcmEditField          matlab.ui.control.NumericEditField
        LegLengthcmEditFieldLabel     matlab.ui.control.Label
        BodyHeightcmEditField         matlab.ui.control.NumericEditField
        BodyHeightcmEditFieldLabel    matlab.ui.control.Label
        BodyWidthcmEditField          matlab.ui.control.NumericEditField
        BodyWidthcmEditFieldLabel     matlab.ui.control.Label
        BodyLengthcmEditField         matlab.ui.control.NumericEditField
        BodyLengthcmEditFieldLabel    matlab.ui.control.Label
        TotalBodyMasskgEditField      matlab.ui.control.NumericEditField
        TotalBodyMassLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        DeliverablesTab               matlab.ui.container.Tab
        RobotDesignOLDTab             matlab.ui.container.Tab
        PowerEditField                matlab.ui.control.NumericEditField
        PowerEditFieldLabel           matlab.ui.control.Label
        SubmitandRunButton            matlab.ui.control.Button
        StallTorqueEditField          matlab.ui.control.NumericEditField
        StallTorqueEditFieldLabel     matlab.ui.control.Label
        MotorMassEditField            matlab.ui.control.NumericEditField
        NoloadSpeedEditField          matlab.ui.control.NumericEditField
        NoloadSpeedEditFieldLabel     matlab.ui.control.Label
        MotorMassEditFieldLabel       matlab.ui.control.Label
        ResultsLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        databasecreator % Dialog app
    end
    
    properties (Access = public)
        MotorMass 
        SimOutputs 
        NoloadSpeed 
        StallTorque
        MotorPower
        testrobot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SubmitandRunButton
        function SubmitandRunButtonPushed(app, event)
            app.MotorMass = app.MotorMassEditField.Value;
            assignin("base","m", app.MotorMass);
            app.SimOutputs = sim("robotModel");

            app.NoloadSpeed = app.SimOutputs.motornoloadspeed.data;
            app.StallTorque = app.SimOutputs.motorstalltorque.data;
            app.MotorPower = app.SimOutputs.motorpower.data;

            app.NoloadSpeedEditField.Value = app.NoloadSpeed;
            app.StallTorqueEditField.Value = app.StallTorque;
            app.PowerEditField.Value = app.MotorPower;
        end

        % Menu selected function: LoadDatabaseMenu
        function LoadDatabaseMenuSelected(app, event)
            loadingdatabasefile = uigetfile(".mat");
            load(loadingdatabasefile);          

            % Display a robot sketch
            % Robot body 
            body = rectangle(app.UIAxes,'Position',[2, 2, app.testrobot.bodylength, app.testrobot.bodyheight],'FaceColor','b');
            % Robot legs
            foreleg = line(app.UIAxes,[3,3], [3,3-testrobot.leglength],'linewidth',5,'color','black');
            rearleg = line(app.UIAxes,[testrobot.bodylength+1 testrobot.bodylength+1 ...
            ], [3 3-testrobot.leglength],'linewidth',5,'color','black');

            % Update the side values
            app.LegThicknesscmEditField.Value = testrobot.legthickness;
            app.LegWidthcmEditField.Value = testrobot.legwidth;
            app.LegLengthcmEditField.Value = testrobot.leglength;
            app.BodyHeightcmEditField.Value = testrobot.bodyheight;
            app.BodyLengthcmEditField.Value = testrobot.bodylength;
            app.BodyWidthcmEditField.Value = testrobot.bodywidth;

            % Update the log
            log1 = sprintf("(%s) Database '%s' loaded.",datetime,loadingdatabasefile);
            app.TextArea_2.Value = log1;
        end

        % Button pushed function: NewDatabaseButton
        function NewDatabaseButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.NewDatabaseButton.Enable = "on";

            % Let the user make a new robot database

            % Call the database app
            app.databasecreator = databasecreator; 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.databasecreator)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.Text = 'Open Project';
            app.RecentProjectsMenu = uimenu(app.FileMenu);
            app.RecentProjectsMenu.Text = 'Recent Projects..';
            app.CISCORDUOMenu = uimenu(app.RecentProjectsMenu);
            app.CISCORDUOMenu.Text = 'CISCOR DUO';
            app.MEExcavatorMenu = uimenu(app.RecentProjectsMenu);
            app.MEExcavatorMenu.Text = 'ME Excavator';
            app.SaveProjectMenu = uimenu(app.FileMenu);
            app.SaveProjectMenu.Text = 'Save Project';
            app.NewDatabaseMenu = uimenu(app.FileMenu);
            app.NewDatabaseMenu.Separator = 'on';
            app.NewDatabaseMenu.Text = 'New Database';
            app.LoadDatabaseMenu = uimenu(app.FileMenu);
            app.LoadDatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDatabaseMenuSelected, true);
            app.LoadDatabaseMenu.Text = 'Load Database';
            app.ExportDatabaseMenu = uimenu(app.FileMenu);
            app.ExportDatabaseMenu.Text = 'Export Database';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.OperationManualMenu = uimenu(app.HelpMenu);
            app.OperationManualMenu.Text = 'Operation Manual';
            app.AboutThisToolMenu = uimenu(app.HelpMenu);
            app.AboutThisToolMenu.Text = 'About This Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseBrowserTab = uitab(app.TabGroup);
            app.DatabaseBrowserTab.Title = 'Database Browser';
            app.AvailableDatabasesPanel = uipanel(app.DatabaseBrowserTab);
            app.AvailableDatabasesPanel.Title = 'Available Databases';
            app.Tree = uitree(app.AvailableDatabasesPanel, 'checkbox');
            app.ETQuadNode = uitreenode(app.Tree);
            app.ETQuadNode.Text = 'ET Quad';
            app.MotorModelNode = uitreenode(app.ETQuadNode);
            app.MotorModelNode.Text = 'Motor Model';
            app.BatteryNode = uitreenode(app.ETQuadNode);
            app.BatteryNode.Text = 'Battery';
            app.LegCharacteristicsNode = uitreenode(app.ETQuadNode);
            app.LegCharacteristicsNode.Text = 'Leg Characteristics';
            app.MinitaurNode = uitreenode(app.Tree);
            app.MinitaurNode.Text = 'Minitaur';
            app.MotorModelNode_2 = uitreenode(app.MinitaurNode);
            app.MotorModelNode_2.Text = 'Motor Model';
            app.BatteryNode_2 = uitreenode(app.MinitaurNode);
            app.BatteryNode_2.Text = 'Battery';
            app.LegCharacteristicsNode_2 = uitreenode(app.MinitaurNode);
            app.LegCharacteristicsNode_2.Text = 'Leg Characteristics';
            app.RHexNode = uitreenode(app.Tree);
            app.RHexNode.Text = 'RHex';
            app.MotorModelNode_3 = uitreenode(app.RHexNode);
            app.MotorModelNode_3.Text = 'Motor Model';
            app.BatteryNode_3 = uitreenode(app.RHexNode);
            app.BatteryNode_3.Text = 'Battery';
            app.LegCharacteristicsNode_3 = uitreenode(app.RHexNode);
            app.LegCharacteristicsNode_3.Text = 'Leg Characteristics';
            app.NewDatabaseButton = uibutton(app.AvailableDatabasesPanel, 'push');
            app.NewDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @NewDatabaseButtonPushed, true);
            app.NewDatabaseButton.Text = 'New Database';
            app.DeleteDatabaseButton = uibutton(app.AvailableDatabasesPanel, 'push');
            app.DeleteDatabaseButton.Text = 'Delete Database';
            app.ExportDatabaseButton = uibutton(app.AvailableDatabasesPanel, 'push');
            app.ExportDatabaseButton.Text = 'Export Database';
            app.SaveSelectionButton = uibutton(app.DatabaseBrowserTab, 'push');
            app.SaveSelectionButton.Text = 'Save Selection';
            app.NextButton = uibutton(app.DatabaseBrowserTab, 'push');
            app.NextButton.Text = 'Next';
            app.DetailsPanel = uipanel(app.DatabaseBrowserTab);
            app.DetailsPanel.Title = 'Details';
            app.TextArea = uitextarea(app.DetailsPanel);
            app.TextArea.Value = {'Database: ET Quad'; ''; 'Date Created: January 10, 2023'; ''; 'Date Modified: January 25, 2023'; ''; 'Available Modules: Motor Model, Battery Model, Leg Characteristics '; ''; 'Author: Team 505'; ''; 'Description: Simple robot model designed by Team 505.'};
            app.RestoreDefaultsButton = uibutton(app.DatabaseBrowserTab, 'push');
            app.RestoreDefaultsButton.Text = 'Restore Defaults';
            app.OverviewPanel = uipanel(app.DatabaseBrowserTab);
            app.OverviewPanel.Title = 'Overview';
            app.Image = uiimage(app.OverviewPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'etquad.jpg');
            app.RobotDesignTab = uitab(app.TabGroup);
            app.RobotDesignTab.Title = 'Robot Design';
            app.SketchViewPanel = uipanel(app.RobotDesignTab);
            app.SketchViewPanel.Title = 'Sketch View';
            app.UIAxes = uiaxes(app.SketchViewPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TotalBodyMassLabel = uilabel(app.SketchViewPanel);
            app.TotalBodyMassLabel.Text = 'Total Body Mass (kg)';
            app.TotalBodyMasskgEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.BodyLengthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.BodyLengthcmEditFieldLabel.Text = 'Body Length (cm)';
            app.BodyLengthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.BodyWidthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.BodyWidthcmEditFieldLabel.Text = 'Body Width (cm)';
            app.BodyWidthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.BodyHeightcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.BodyHeightcmEditFieldLabel.Text = 'Body Height (cm)';
            app.BodyHeightcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.LegLengthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.LegLengthcmEditFieldLabel.Text = 'Leg Length (cm)';
            app.LegLengthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.LegWidthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.LegWidthcmEditFieldLabel.Text = 'Leg Width (cm)';
            app.LegWidthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.LegThicknesscmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.LegThicknesscmEditFieldLabel.Text = 'Leg Thickness (cm)';
            app.LegThicknesscmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.BatteryPowerWEditFieldLabel = uilabel(app.SketchViewPanel);
            app.BatteryPowerWEditFieldLabel.Text = 'Battery Power (W)';
            app.BatteryPowerWEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.StallTorqueNmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.StallTorqueNmEditFieldLabel.Text = 'Stall Torque (Nm)';
            app.StallTorqueNmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.NoloadSpeedRPMEditFieldLabel = uilabel(app.SketchViewPanel);
            app.NoloadSpeedRPMEditFieldLabel.Text = 'No-load Speed (RPM)';
            app.NoloadSpeedRPMEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.LogPanel = uipanel(app.RobotDesignTab);
            app.LogPanel.Title = 'Log';
            app.TextArea_2 = uitextarea(app.LogPanel);
            app.LoggingLevelDropDownLabel = uilabel(app.LogPanel);
            app.LoggingLevelDropDownLabel.Text = 'Logging Level';
            app.LoggingLevelDropDown = uidropdown(app.LogPanel);
            app.LoggingLevelDropDown.Items = {'Simple', 'Verbose'};
            app.LoggingLevelDropDown.Value = 'Simple';
            app.ExportLogButton = uibutton(app.LogPanel, 'push');
            app.ExportLogButton.Text = 'Export Log';
            app.SaveSelectionButton_2 = uibutton(app.RobotDesignTab, 'push');
            app.SaveSelectionButton_2.Text = 'Save Selection';
            app.NextButton_2 = uibutton(app.RobotDesignTab, 'push');
            app.NextButton_2.Text = 'Next';
            app.PreviousButton = uibutton(app.RobotDesignTab, 'push');
            app.PreviousButton.Text = 'Previous';
            app.ScalingRulesPanel = uipanel(app.RobotDesignTab);
            app.ScalingRulesPanel.Title = 'Scaling Rules';
            app.SubmitButton = uibutton(app.RobotDesignTab, 'push');
            app.SubmitButton.Text = 'Submit';
            app.DeliverablesTab = uitab(app.TabGroup);
            app.DeliverablesTab.Title = 'Deliverables';
            app.RobotDesignOLDTab = uitab(app.TabGroup);
            app.RobotDesignOLDTab.Title = 'Robot Design (OLD)';
            app.ResultsLabel = uilabel(app.RobotDesignOLDTab);
            app.ResultsLabel.Text = 'Results';
            app.MotorMassEditFieldLabel = uilabel(app.RobotDesignOLDTab);
            app.MotorMassEditFieldLabel.Text = 'Motor Mass (kg)';
            app.NoloadSpeedEditFieldLabel = uilabel(app.RobotDesignOLDTab);
            app.NoloadSpeedEditFieldLabel.Text = 'No-load Speed (RPM)';
            app.NoloadSpeedEditField = uieditfield(app.RobotDesignOLDTab, 'numeric');
            app.MotorMassEditField = uieditfield(app.RobotDesignOLDTab, 'numeric');
            app.MotorMassEditField.Limits = [1e-06 Inf];
            app.MotorMassEditField.Value = 1;
            app.StallTorqueEditFieldLabel = uilabel(app.RobotDesignOLDTab);
            app.StallTorqueEditFieldLabel.Text = 'Stall Torque (Nm)';
            app.StallTorqueEditField = uieditfield(app.RobotDesignOLDTab, 'numeric');
            app.SubmitandRunButton = uibutton(app.RobotDesignOLDTab, 'push');
            app.SubmitandRunButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitandRunButtonPushed, true);
            app.SubmitandRunButton.Text = 'Submit and Run';
            app.PowerEditFieldLabel = uilabel(app.RobotDesignOLDTab);
            app.PowerEditFieldLabel.Text = 'Power (W)';
            app.PowerEditField = uieditfield(app.RobotDesignOLDTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mockuppart3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end